classdef RayTracingProgram < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        SetupTab                  matlab.ui.container.Tab
        TableGoebel               matlab.ui.control.Table
        TableSources              matlab.ui.control.Table
        TableSlits                matlab.ui.control.Table
        TableDetectors            matlab.ui.control.Table
        GoebelLabel               matlab.ui.control.Label
        SourcesLabel              matlab.ui.control.Label
        SlitsLabel                matlab.ui.control.Label
        DetectorsLabel            matlab.ui.control.Label
        CreateSetupButton         matlab.ui.control.Button
        PlotSetupCheckBox         matlab.ui.control.CheckBox
        TableSample               matlab.ui.control.Table
        SampleLabel               matlab.ui.control.Label
        Panel                     matlab.ui.container.Panel
        ButtonGoebelPlus          matlab.ui.control.Button
        ButtonGoebelMinus         matlab.ui.control.Button
        ButtonGoebelUp            matlab.ui.control.Button
        ButtonGoebelDown          matlab.ui.control.Button
        Panel_2                   matlab.ui.container.Panel
        ButtonSourcesPlus         matlab.ui.control.Button
        ButtonSourcesMinus        matlab.ui.control.Button
        ButtonSourcesUp           matlab.ui.control.Button
        ButtonSourcesDown         matlab.ui.control.Button
        Panel_3                   matlab.ui.container.Panel
        ButtonSlitsPlus           matlab.ui.control.Button
        ButtonSlitsMinus          matlab.ui.control.Button
        ButtonSlitsUp             matlab.ui.control.Button
        ButtonSlitsDown           matlab.ui.control.Button
        Panel_4                   matlab.ui.container.Panel
        ButtonDetectorsPlus       matlab.ui.control.Button
        ButtonDetectorsMinus      matlab.ui.control.Button
        ButtonDetectorsUp         matlab.ui.control.Button
        ButtonDetectorsDown       matlab.ui.control.Button
        Panel_5                   matlab.ui.container.Panel
        ButtonSamplePlus          matlab.ui.control.Button
        ButtonSampleMinus         matlab.ui.control.Button
        SetupAxes                 matlab.ui.control.UIAxes
        Panel_6                   matlab.ui.container.Panel
        detectorslitsLabel        matlab.ui.control.Label
        sampleLabel               matlab.ui.control.Label
        GoebelsourcesLabel        matlab.ui.control.Label
        sourceslitsLabel          matlab.ui.control.Label
        detectorsLabel            matlab.ui.control.Label
        BeamonCheckbox            matlab.ui.control.CheckBox
        iLabel                    matlab.ui.control.Label
        iLabel_2                  matlab.ui.control.Label
        iLabel_3                  matlab.ui.control.Label
        iLabel_4                  matlab.ui.control.Label
        iLabel_5                  matlab.ui.control.Label
        iLabel_6                  matlab.ui.control.Label
        MeasureTab                matlab.ui.container.Tab
        ScantypeDropDownLabel     matlab.ui.control.Label
        ScantypeDropDown          matlab.ui.control.DropDown
        StartButton               matlab.ui.control.Button
        alphaEditFieldLabel       matlab.ui.control.Label
        alphaEditField            matlab.ui.control.NumericEditField
        betaEditFieldLabel        matlab.ui.control.Label
        betaEditField             matlab.ui.control.NumericEditField
        ttEditFieldLabel          matlab.ui.control.Label
        ttEditField               matlab.ui.control.NumericEditField
        hEditFieldLabel           matlab.ui.control.Label
        hEditField                matlab.ui.control.NumericEditField
        alpha_maxEditFieldLabel   matlab.ui.control.Label
        alpha_maxEditField        matlab.ui.control.NumericEditField
        beta_maxEditFieldLabel    matlab.ui.control.Label
        beta_maxEditField         matlab.ui.control.NumericEditField
        tt_maxEditFieldLabel      matlab.ui.control.Label
        tt_maxEditField           matlab.ui.control.NumericEditField
        h_maxEditFieldLabel       matlab.ui.control.Label
        h_maxEditField            matlab.ui.control.NumericEditField
        alpha_incrEditFieldLabel  matlab.ui.control.Label
        alpha_incrEditField       matlab.ui.control.NumericEditField
        beta_incrEditFieldLabel   matlab.ui.control.Label
        beta_incrEditField        matlab.ui.control.NumericEditField
        tt_incrEditFieldLabel     matlab.ui.control.Label
        tt_incrEditField          matlab.ui.control.NumericEditField
        h_incrEditFieldLabel      matlab.ui.control.Label
        h_incrEditField           matlab.ui.control.NumericEditField
        alpha_minLabel            matlab.ui.control.Label
        alpha_minEditField        matlab.ui.control.NumericEditField
        beta_minEditFieldLabel    matlab.ui.control.Label
        beta_minEditField         matlab.ui.control.NumericEditField
        tt_minEditFieldLabel      matlab.ui.control.Label
        tt_minEditField           matlab.ui.control.NumericEditField
        h_minEditFieldLabel       matlab.ui.control.Label
        h_minEditField            matlab.ui.control.NumericEditField
        Label                     matlab.ui.control.Label
        PanelTubeScan             matlab.ui.container.Panel
        AxesTubeScan              matlab.ui.control.UIAxes
        PanelDetectorScan         matlab.ui.container.Panel
        AxesDetectorScan          matlab.ui.control.UIAxes
        PanelRockingCurve         matlab.ui.container.Panel
        AxesRockingCurve          matlab.ui.control.UIAxes
        PanelHeightScan           matlab.ui.container.Panel
        AxesHeightScan            matlab.ui.control.UIAxes
        Panelt2t                  matlab.ui.container.Panel
        Axest2t                   matlab.ui.control.UIAxes
        PanelDetectorImage        matlab.ui.container.Panel
        AxesDetectorImage         matlab.ui.control.UIAxes
        PanelDetectorImageSarm    matlab.ui.container.Panel
        AxesDetectorImageSarm     matlab.ui.control.UIAxes
        MultiplePlotsCheckBox     matlab.ui.control.CheckBox
        SaveButton                matlab.ui.control.Button
        PlotNumberSpinnerLabel    matlab.ui.control.Label
        PlotNumberSpinner         matlab.ui.control.Spinner
        iLabel_7                  matlab.ui.control.Label
        ListBox                   matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        setup % struct with all the components used for measuring
        %row indices in tables
        rowGoebel=0;
        rowSources=0;
        rowSlits=0;
        rowDetectors=0;
        measurements % struct with all the measured data displayed in the figures
        
        flag = false; %if true: callback gets interrupted
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Create waiting bar
            d = uiprogressdlg(app.UIFigure,'Title','Starting App','Message','Filling Tables','Indeterminate','on');
            %Initialize setup struct
            app.setup=startsetup();
            %fill tables with default setup
            tGoebel=table(160,90,1.35,uint16(1E3),uint16(1E3),0.03,'VariableNames',{'d','gamma','fwhm','NGoebel','NRays','delta'});
            tSlits=table(categorical(["sourcearm";"detectorarm";"detectorarm"]),[153;260;300],[0.1;0.6;0.1],[90;90;90],'VariableNames',{'Arm','d','w','gamma'});
            tDetectors=table(categorical("detectorarm",{'sourcearm','detectorarm'}),310,1,90,uint16(100),'VariableNames',{'arm','d','w','gamma','NPixels'});
            tSample=table(10,0,0,'VariableNames',{'w','offset','gamma'});
            app.TableGoebel.Data=tGoebel;
            app.TableSlits.Data=tSlits;
            app.TableDetectors.Data=tDetectors;
            app.TableSample.Data=tSample;
            %No measuring before creating setup
            app.alphaEditField.Visible=false;
            app.alpha_minEditField.Visible=false;
            app.alpha_maxEditField.Visible=false;
            app.alpha_incrEditField.Visible=false;
            app.alphaEditFieldLabel.Visible=false;
            app.alpha_minLabel.Visible=false;
            app.alpha_maxEditFieldLabel.Visible=false;
            app.alpha_incrEditFieldLabel.Visible=false;
            app.betaEditField.Visible=false;
            app.beta_minEditField.Visible=false;
            app.beta_maxEditField.Visible=false;
            app.beta_incrEditField.Visible=false;
            app.betaEditFieldLabel.Visible=false;
            app.beta_minEditFieldLabel.Visible=false;
            app.beta_maxEditFieldLabel.Visible=false;
            app.beta_incrEditFieldLabel.Visible=false;
            app.ttEditField.Visible=false;
            app.tt_minEditField.Visible=false;
            app.tt_maxEditField.Visible=false;
            app.tt_incrEditField.Visible=false;
            app.ttEditFieldLabel.Visible=false;
            app.tt_minEditFieldLabel.Visible=false;
            app.tt_maxEditFieldLabel.Visible=false;
            app.tt_incrEditFieldLabel.Visible=false;
            app.hEditField.Visible=false;
            app.h_minEditField.Visible=false;
            app.h_maxEditField.Visible=false;
            app.h_incrEditField.Visible=false;
            app.hEditFieldLabel.Visible=false;
            app.h_minEditFieldLabel.Visible=false;
            app.h_maxEditFieldLabel.Visible=false;
            app.h_incrEditFieldLabel.Visible=false;
            
            app.StartButton.Visible=false;
            app.ScantypeDropDown.Visible=false;
            app.ScantypeDropDownLabel.Visible=false;
            app.PlotNumberSpinner.Visible=false;
            app.SaveButton.Visible=false;
            app.MultiplePlotsCheckBox.Visible=false;
            
            app.PanelTubeScan.Visible=false;
            app.PanelDetectorScan.Visible=false;
            app.PanelRockingCurve.Visible=false;
            app.PanelHeightScan.Visible=false;
            app.Panelt2t.Visible=false;
            app.PanelDetectorImage.Visible=false;
            app.PanelDetectorImageSarm.Visible=false;
            
            close(d);
        end

        % Cell selection callback: TableGoebel
        function TableGoebelCellSelection(app, event)
            %save last selected row in Goebel table.
            indices = event.Indices;
            if isempty(indices)==false
                app.rowGoebel=indices(1);
            end
        end

        % Cell selection callback: TableSources
        function TableSourcesCellSelection(app, event)
            %save last selected row in Sources table
            indices = event.Indices;
            if isempty(indices)==false
                app.rowSources=indices(1);
            end
        end

        % Cell selection callback: TableSlits
        function TableSlitsCellSelection(app, event)
            %save last selected row in slits table.
            indices = event.Indices;
            if isempty(indices)==false
                 app.rowSlits=indices(1);
            end
        end

        % Cell selection callback: TableDetectors
        function TableDetectorsCellSelection(app, event)
            %save last selected row in detectors table.
            indices = event.Indices;
            if isempty(indices)==false
                app.rowDetectors=indices(1);
            end
        end

        % Button pushed function: ButtonGoebelPlus
        function ButtonGoebelPlusPushed(app, event)
            %add row on button
            newrow=table(160,90,1.35,uint16(1E3),uint16(1E3),0.3,'VariableNames',{'d','gamma','fwhm','NGoebel','NRays','delta'});
            app.TableGoebel.Data=[app.TableGoebel.Data;newrow];
        end

        % Button pushed function: ButtonSourcesPlus
        function ButtonSourcesPlusPushed(app, event)
            %add row on button
            newrow=table(160,0,90,0,0.03,uint16(10),'VariableNames',{'d','a','gamma','phi','delta','NRays'});
            app.TableSources.Data=[app.TableSources.Data;newrow];
        end

        % Button pushed function: ButtonSlitsPlus
        function ButtonSlitsPlusPushed(app, event)
            %add row on button
            newrow=table(categorical("detectorarm"),300,0.1,90,'VariableNames',{'Arm','d','w','gamma'});
            app.TableSlits.Data=[app.TableSlits.Data;newrow];
        end

        % Button pushed function: ButtonDetectorsPlus
        function ButtonDetectorsPlusPushed(app, event)
            %add row on button
            newrow=table(categorical("detectorarm"),310,1,90,uint16(100),'VariableNames',{'arm','d','w','gamma','NPixels'});
            app.TableDetectors.Data=[app.TableDetectors.Data;newrow];
        end

        % Button pushed function: ButtonSamplePlus
        function ButtonSamplePlusPushed(app, event)
            %add row (max 1 row)
            newrow=table(10,0,90,'VariableNames',{'h','offset','gamma'});
            if isempty(app.TableSample.Data)==true
              app.TableSample.Data=[app.TableSample.Data;newrow];
            end
        end

        % Button pushed function: ButtonGoebelMinus
        function ButtonGoebelMinusPushed(app, event)
            %delete selected row
            if isempty(app.TableGoebel.Data)==false && app.rowGoebel > 0
                app.TableGoebel.Data(app.rowGoebel,:)=[];
            end
            app.rowGoebel=app.rowGoebel-1;
        end

        % Button pushed function: ButtonSourcesMinus
        function ButtonSourcesMinusPushed(app, event)
            %delete selected row
            if isempty(app.TableSources.Data)==false && app.rowSources > 0
                app.TableSources.Data(app.rowSources,:)=[];
            end
            app.rowSources=app.rowSources-1;
        end

        % Button pushed function: ButtonSlitsMinus
        function ButtonSlitsMinusPushed(app, event)
            %delete selected row
            if isempty(app.TableSlits.Data)==false && app.rowSlits > 0
                app.TableSlits.Data(app.rowSlits,:)=[];
            end
            app.rowSlits=app.rowSlits-1;
        end

        % Button pushed function: ButtonDetectorsMinus
        function ButtonDetectorsMinusPushed(app, event)
            %delete selected row
            if isempty(app.TableDetectors.Data)==false && app.rowDetectors > 0
                app.TableDetectors.Data(app.rowDetectors,:)=[];
            end
            app.rowDetectors=app.rowDetectors-1;
        end

        % Button pushed function: ButtonSampleMinus
        function ButtonSampleMinusPushed(app, event)
            %delete sample
            app.TableSample.Data=[];
        end

        % Button pushed function: ButtonGoebelUp
        function ButtonGoebelUpPushed(app, event)
            %move selected row one up
            idx=app.rowGoebel;
            if idx>1
                t=app.TableGoebel.Data;
                toprow=t(idx-1,:);
                buttonrow=t(idx,:);
                app.TableGoebel.Data(idx-1,:)=buttonrow;
                app.TableGoebel.Data(idx,:)=toprow;
                app.rowGoebel=idx-1;
            end
            
        end

        % Button pushed function: ButtonSourcesUp
        function ButtonSourcesUpPushed(app, event)
             %move selected row one up
            idx=app.rowSources;
            if idx>1
                t=app.TableSources.Data;
                toprow=t(idx-1,:);
                buttonrow=t(idx,:);
                app.TableSources.Data(idx-1,:)=buttonrow;
                app.TableSources.Data(idx,:)=toprow;
                app.rowSources=idx-1;
            end
            
        end

        % Button pushed function: ButtonSlitsUp
        function ButtonSlitsUpPushed(app, event)
             %move selected row one up
            idx=app.rowSlits;
            if idx>1
                t=app.TableSlits.Data;
                toprow=t(idx-1,:);
                buttonrow=t(idx,:);
                app.TableSlits.Data(idx-1,:)=buttonrow;
                app.TableSlits.Data(idx,:)=toprow;
                app.rowSlits=idx-1;
            end
            
        end

        % Button pushed function: ButtonDetectorsUp
        function ButtonDetectorsUpPushed(app, event)
             %move selected row one up
            idx=app.rowDetectors;
            if idx>1
                t=app.TableDetectors.Data;
                toprow=t(idx-1,:);
                buttonrow=t(idx,:);
                app.TableDetectors.Data(idx-1,:)=buttonrow;
                app.TableDetectors.Data(idx,:)=toprow;
                app.rowDetectors=idx-1;
            end
            
        end

        % Button pushed function: ButtonGoebelDown
        function ButtonGoebelDownPushed(app, event)
            %move selected row down
            idx=app.rowGoebel;
            t=app.TableGoebel.Data;
            [Nrows,~]=size(t);
            if idx>0 && idx<Nrows
                toprow=t(idx,:);
                buttonrow=t(idx+1,:);
                app.TableGoebel.Data(idx,:)=buttonrow;
                app.TableGoebel.Data(idx+1,:)=toprow;
                app.rowGoebel=idx+1;
            end
        end

        % Button pushed function: ButtonSourcesDown
        function ButtonSourcesDownPushed(app, event)
            %move selected row down
            idx=app.rowSources;
            t=app.TableSources.Data;
            [Nrows,~]=size(t);
            if idx>0 && idx<Nrows
                toprow=t(idx,:);
                buttonrow=t(idx+1,:);
                app.TableSources.Data(idx,:)=buttonrow;
                app.TableSources.Data(idx+1,:)=toprow;
                app.rowSources=idx+1;
            end
        end

        % Button pushed function: ButtonSlitsDown
        function ButtonSlitsDownPushed(app, event)
            %move selected row down
            idx=app.rowSlits;
            t=app.TableSlits.Data;
            [Nrows,~]=size(t);
            if idx>0 && idx<Nrows
                toprow=t(idx,:);
                buttonrow=t(idx+1,:);
                app.TableSlits.Data(idx,:)=buttonrow;
                app.TableSlits.Data(idx+1,:)=toprow;
                app.rowSlits=idx+1;
            end
        end

        % Button pushed function: ButtonDetectorsDown
        function ButtonDetectorsDownPushed(app, event)
            %move selected row down
            idx=app.rowDetectors;
            t=app.TableDetectors.Data;
            [Nrows,~]=size(t);
            if idx>0 && idx<Nrows
                toprow=t(idx,:);
                buttonrow=t(idx+1,:);
                app.TableDetectors.Data(idx,:)=buttonrow;
                app.TableDetectors.Data(idx+1,:)=toprow;
                app.rowDetectors=idx+1;
            end
        end

        % Button pushed function: CreateSetupButton
        function CreateSetupButtonPushed(app, event)
            %Create Waiting Bar
            d = uiprogressdlg(app.UIFigure,'Title','Creating Setup','Message','Start Setup','Indeterminate','on');
            
            app.setup=startsetup();
            setup=app.setup;
            %Validate inputs
            valid=true; %turns false when one input data not valid
            d.Message='Validate input data';
            %Goebel
            t=app.TableGoebel.Data;
            if isempty(t)==false
                [Nrows,~]=size(t);
                for k=1:Nrows
                    if t{k,4}> 1E4 || t{k,5} > 1E4 
                        app.ListBox.Items{end+1}='Could not create setup: In Goebel table: NGoebel and NRays must be smaller than 1E4';
                        app.ListBox.scroll('bottom');
                        valid=false;
                    end
                    if t{k,1}<0
                        app.ListBox.Items{end+1}='Could not create setup: In Goebel table: d must be greater than 0';
                        app.ListBox.scroll('bottom');
                        valid=false;
                    end
                    if t{k,2}< 0 || t{k,2}> 180
                        app.ListBox.Items{end+1}='Could not create setup: In Goebel table: gamma must be between 0 and 180';
                        app.ListBox.scroll('bottom');
                        valid=false;
                    end
                    if t{k,3}<0
                        app.ListBox.Items{end+1}='Could not create setup: In Goebel table: fwhm must be greater or equal to 0';
                        app.ListBox.scroll('bottom');
                        valid=false;
                    end
                    if t{k,6}<0
                        app.ListBox.Items{end+1}='Could not create setup: In Goebel table: delta must be greater or equal to 0';
                        app.ListBox.scroll('bottom');
                        valid=false;
                    end
                end
            end
            %Sources
            t=app.TableSources.Data;
            if isempty(t)==false
                [Nrows,~]=size(t);
                for k=1:Nrows
                    if t{k,6}>1E4
                        app.ListBox.Items{end+1}='Could not create setup: In Sources table: NRays must be smaller than 1E4';
                        app.ListBox.scroll('bottom');
                        valid=false;
                    end
                    if t{k,1}<0
                        app.ListBox.Items{end+1}='Could not create setup: In Sources table: d must be greater than 0';
                        app.ListBox.scroll('bottom');
                        valid=false;
                    end
                    if t{k,3}<0 || t{k,3}>180
                        app.ListBox.Items{end+1}='Could not create setup: In Sources table: gamma must be between 0 and 180';
                        app.ListBox.scroll('bottom');
                        valid=false;
                    end
                    if t{k,5}<0
                        app.ListBox.Items{end+1}='Could not create setup: In Sources table: delta must be greater than or equal to 0';
                        app.ListBox.scroll('bottom');
                        valid=false;
                    end
                end
            end
            %Slits
            t=app.TableSlits.Data;
            if isempty(t)==false
                [Nrows,~]=size(t);
                for k=1:Nrows
                    if t{k,2}<0
                        app.ListBox.Items{end+1}='Could not create setup: In Slits table: d must be greater than or equal to 0';
                        app.ListBox.scroll('bottom');
                        valid=false;
                    end
                end
            end
            
            
            if valid==false
                return;
            end
            
            
            %Add everything to setup
            
            %Goebel
            d.Message='Add Goebel';
            t=app.TableGoebel.Data;
            if isempty(t)==false
                [Nrows,~]=size(t);
                for k=1:Nrows
                    setup=add_goebel(setup,t{k,1},t{k,2},t{k,3},t{k,4},t{k,5},t{k,6});
                end
            end
            %Sources
            d.Message='Add Sources';
            t=app.TableSources.Data;
            if isempty(t)==false
                [Nrows,~]=size(t);
                for k=1:Nrows
                    setup=add_source_multipleRays(setup,t{k,1},t{k,2},t{k,3},t{k,4},t{k,5},t{k,6});
                end
            end
            %Slits
            d.Message='Add Slits';
            t=app.TableSlits.Data;
            if isempty(t)==false
                [Nrows,~]=size(t);
                for k=1:Nrows
                    if t{k,1}=='sourcearm'
                        setup=add_sslit(setup,t{k,2},t{k,3},t{k,4});
                    else
                        setup=add_dslit(setup,t{k,2},t{k,3},t{k,4});
                    end
                end
            end
            %Detectors
            d.Message='Add Detectors';
            t=app.TableDetectors.Data;
            if isempty(t)==false
                [Nrows,~]=size(t);
                for k=1:Nrows
                    if t{k,1}=='sourcearm'
                        setup=add_detector_sarm(setup,t{k,2},t{k,3},t{k,4},t{k,5});
                    else
                        setup=add_detector(setup,t{k,2},t{k,3},t{k,4},t{k,5});
                    end
                end
            end
            %Sample
            d.Message='Add Sample';
            t=app.TableSample.Data;
            if isempty(t)==false
                setup=add_sample(setup,t{1,1},t{1,2},t{1,3});
            end
            
            app.setup=setup;
            
            %Plot setup
            d.Message='Plot Setup';
            if app.PlotSetupCheckBox.Value==true
                hold(app.SetupAxes,'off');
                app.SetupAxes.XLimMode='auto';
                app.SetupAxes.YLimMode='auto';
                %check if there are too many rays for plotting them
                if app.BeamonCheckbox.Value==true
                    %count rays
                    u=setup.sources.u;
                    if isempty(u)==false
                        Nrays=0;
                        for k=1:length(u)
                            uk=u{k};
                            [~,l]=size(uk);
                            Nrays=Nrays+l;
                            if Nrays>100
                                app.BeamonCheckbox.Value=false;
                                app.ListBox.Items{end+1}='Could not plot rays: Maximum limit of 100 rays exceeded.';
                                app.ListBox.scroll('bottom');
                                break;
                            end
                        end
                    end
                end
                %plot
                plot_setup2(app.SetupAxes,setup,0,0,app.BeamonCheckbox.Value);
            end


            %Initialize Measuring Tab
            if app.CreateSetupButton.Text=='Create Setup'
                %Default ScanType: TubeScan
                app.alphaEditField.Visible=false;
                app.alpha_minEditField.Visible=true;
                app.alpha_maxEditField.Visible=true;
                app.alpha_incrEditField.Visible=true;
                app.alphaEditFieldLabel.Visible=false;
                app.alpha_minLabel.Visible=true;
                app.alpha_maxEditFieldLabel.Visible=true;
                app.alpha_incrEditFieldLabel.Visible=true;
                app.betaEditField.Visible=true;
                app.beta_minEditField.Visible=false;
                app.beta_maxEditField.Visible=false;
                app.beta_incrEditField.Visible=false;
                app.betaEditFieldLabel.Visible=true;
                app.beta_minEditFieldLabel.Visible=false;
                app.beta_maxEditFieldLabel.Visible=false;
                app.beta_incrEditFieldLabel.Visible=false;
                app.ttEditField.Visible=false;
                app.tt_minEditField.Visible=false;
                app.tt_maxEditField.Visible=false;
                app.tt_incrEditField.Visible=false;
                app.ttEditFieldLabel.Visible=false;
                app.tt_minEditFieldLabel.Visible=false;
                app.tt_maxEditFieldLabel.Visible=false;
                app.tt_incrEditFieldLabel.Visible=false;
                app.hEditField.Visible=false;
                app.h_minEditField.Visible=false;
                app.h_maxEditField.Visible=false;
                app.h_incrEditField.Visible=false;
                app.hEditFieldLabel.Visible=false;
                app.h_minEditFieldLabel.Visible=false;
                app.h_maxEditFieldLabel.Visible=false;
                app.h_incrEditFieldLabel.Visible=false;
                
                app.StartButton.Visible=true;
                app.Label.Visible=false;
                app.ScantypeDropDown.Visible=true;
                app.ScantypeDropDown.Visible=true;
                app.SaveButton.Visible=true;
                app.MultiplePlotsCheckBox.Visible=true;
                
                app.PanelTubeScan.Visible=true;
                
                app.CreateSetupButton.Text='Change setup';
                %initialize measurements struct
                app.measurements=start_measurements();
            end
            close(d);
        end

        % Value changed function: ScantypeDropDown
        function ScantypeDropDownValueChanged(app, event)
           %Items visibility regulation dependant on chosen Scantype
            value = app.ScantypeDropDown.Value;
            switch value
                case 'Tube Scan'
                    app.alphaEditField.Visible=false;
                    app.alpha_minEditField.Visible=true;
                    app.alpha_maxEditField.Visible=true;
                    app.alpha_incrEditField.Visible=true;
                    app.alphaEditFieldLabel.Visible=false;
                    app.alpha_minLabel.Visible=true;
                    app.alpha_maxEditFieldLabel.Visible=true;
                    app.alpha_incrEditFieldLabel.Visible=true;
                    app.betaEditField.Visible=true;
                    app.beta_minEditField.Visible=false;
                    app.beta_maxEditField.Visible=false;
                    app.beta_incrEditField.Visible=false;
                    app.betaEditFieldLabel.Visible=true;
                    app.beta_minEditFieldLabel.Visible=false;
                    app.beta_maxEditFieldLabel.Visible=false;
                    app.beta_incrEditFieldLabel.Visible=false;
                    app.ttEditField.Visible=false;
                    app.tt_minEditField.Visible=false;
                    app.tt_maxEditField.Visible=false;
                    app.tt_incrEditField.Visible=false;
                    app.ttEditFieldLabel.Visible=false;
                    app.tt_minEditFieldLabel.Visible=false;
                    app.tt_maxEditFieldLabel.Visible=false;
                    app.tt_incrEditFieldLabel.Visible=false;
                    app.hEditField.Visible=false;
                    app.h_minEditField.Visible=false;
                    app.h_maxEditField.Visible=false;
                    app.h_incrEditField.Visible=false;
                    app.hEditFieldLabel.Visible=false;
                    app.h_minEditFieldLabel.Visible=false;
                    app.h_maxEditFieldLabel.Visible=false;
                    app.h_incrEditFieldLabel.Visible=false;
                    app.PanelTubeScan.Visible=true;
                    app.PanelDetectorScan.Visible=false;
                    app.PanelRockingCurve.Visible=false;
                    app.PanelHeightScan.Visible=false;
                    app.Panelt2t.Visible=false;
                    app.PanelDetectorImage.Visible=false;
                    app.PanelDetectorImageSarm.Visible=false;
                    %Adjust Plot Number Spinner
                    Nplots=length(app.AxesTubeScan.Children);
                    app.PlotNumberSpinner.Value=1;
                    if Nplots>1
                        app.PlotNumberSpinner.Limits=[1 Nplots];
                        app.PlotNumberSpinner.Visible=true;
                    else
                        app.PlotNumberSpinner.Visible=false;
                    end
                case 'Detector Scan'
                    app.alphaEditField.Visible=true;
                    app.alpha_minEditField.Visible=false;
                    app.alpha_maxEditField.Visible=false;
                    app.alpha_incrEditField.Visible=false;
                    app.alphaEditFieldLabel.Visible=true;
                    app.alpha_minLabel.Visible=false;
                    app.alpha_maxEditFieldLabel.Visible=false;
                    app.alpha_incrEditFieldLabel.Visible=false;
                    app.betaEditField.Visible=false;
                    app.beta_minEditField.Visible=true;
                    app.beta_maxEditField.Visible=true;
                    app.beta_incrEditField.Visible=true;
                    app.betaEditFieldLabel.Visible=false;
                    app.beta_minEditFieldLabel.Visible=true;
                    app.beta_maxEditFieldLabel.Visible=true;
                    app.beta_incrEditFieldLabel.Visible=true;
                    app.ttEditField.Visible=false;
                    app.tt_minEditField.Visible=false;
                    app.tt_maxEditField.Visible=false;
                    app.tt_incrEditField.Visible=false;
                    app.ttEditFieldLabel.Visible=false;
                    app.tt_minEditFieldLabel.Visible=false;
                    app.tt_maxEditFieldLabel.Visible=false;
                    app.tt_incrEditFieldLabel.Visible=false;
                    app.hEditField.Visible=false;
                    app.h_minEditField.Visible=false;
                    app.h_maxEditField.Visible=false;
                    app.h_incrEditField.Visible=false;
                    app.hEditFieldLabel.Visible=false;
                    app.h_minEditFieldLabel.Visible=false;
                    app.h_maxEditFieldLabel.Visible=false;
                    app.h_incrEditFieldLabel.Visible=false;
                    app.PanelTubeScan.Visible=false;
                    app.PanelDetectorScan.Visible=true;
                    app.PanelRockingCurve.Visible=false;
                    app.PanelHeightScan.Visible=false;
                    app.Panelt2t.Visible=false;
                    app.PanelDetectorImage.Visible=false;
                    app.PanelDetectorImageSarm.Visible=false;
                    %Adjust Plot Number Spinner
                    Nplots=length(app.AxesDetectorScan.Children);
                    app.PlotNumberSpinner.Value=1;
                    if Nplots>1
                        app.PlotNumberSpinner.Limits=[1 Nplots];
                        app.PlotNumberSpinner.Visible=true;
                    else
                        app.PlotNumberSpinner.Visible=false;
                    end
                case 'Rocking Curve'
                    app.alphaEditField.Visible=false;
                    app.alpha_minEditField.Visible=true;
                    app.alpha_maxEditField.Visible=true;
                    app.alpha_incrEditField.Visible=true;
                    app.alphaEditFieldLabel.Visible=false;
                    app.alpha_minLabel.Visible=true;
                    app.alpha_maxEditFieldLabel.Visible=true;
                    app.alpha_incrEditFieldLabel.Visible=true;
                    app.betaEditField.Visible=false;
                    app.beta_minEditField.Visible=false;
                    app.beta_maxEditField.Visible=false;
                    app.beta_incrEditField.Visible=false;
                    app.betaEditFieldLabel.Visible=false;
                    app.beta_minEditFieldLabel.Visible=false;
                    app.beta_maxEditFieldLabel.Visible=false;
                    app.beta_incrEditFieldLabel.Visible=false;
                    app.ttEditField.Visible=true;
                    app.tt_minEditField.Visible=false;
                    app.tt_maxEditField.Visible=false;
                    app.tt_incrEditField.Visible=false;
                    app.ttEditFieldLabel.Visible=true;
                    app.tt_minEditFieldLabel.Visible=false;
                    app.tt_maxEditFieldLabel.Visible=false;
                    app.tt_incrEditFieldLabel.Visible=false;
                    app.hEditField.Visible=true;
                    app.h_minEditField.Visible=false;
                    app.h_maxEditField.Visible=false;
                    app.h_incrEditField.Visible=false;
                    app.hEditFieldLabel.Visible=true;
                    app.h_minEditFieldLabel.Visible=false;
                    app.h_maxEditFieldLabel.Visible=false;
                    app.h_incrEditFieldLabel.Visible=false;
                    app.PanelTubeScan.Visible=false;
                    app.PanelDetectorScan.Visible=false;
                    app.PanelRockingCurve.Visible=true;
                    app.PanelHeightScan.Visible=false;
                    app.Panelt2t.Visible=false;
                    app.PanelDetectorImage.Visible=false;
                    app.PanelDetectorImageSarm.Visible=false;
                    %Adjust Plot Number Spinner
                    Nplots=length(app.AxesRockingCurve.Children);
                    app.PlotNumberSpinner.Value=1;
                    if Nplots>1
                        app.PlotNumberSpinner.Limits=[1 Nplots];
                        app.PlotNumberSpinner.Visible=true;
                    else
                        app.PlotNumberSpinner.Visible=false;
                    end
                case 'Height Scan'
                    app.alphaEditField.Visible=true;
                    app.alpha_minEditField.Visible=false;
                    app.alpha_maxEditField.Visible=false;
                    app.alpha_incrEditField.Visible=false;
                    app.alphaEditFieldLabel.Visible=true;
                    app.alpha_minLabel.Visible=false;
                    app.alpha_maxEditFieldLabel.Visible=false;
                    app.alpha_incrEditFieldLabel.Visible=false;
                    app.betaEditField.Visible=true;
                    app.beta_minEditField.Visible=false;
                    app.beta_maxEditField.Visible=false;
                    app.beta_incrEditField.Visible=false;
                    app.betaEditFieldLabel.Visible=true;
                    app.beta_minEditFieldLabel.Visible=false;
                    app.beta_maxEditFieldLabel.Visible=false;
                    app.beta_incrEditFieldLabel.Visible=false;
                    app.ttEditField.Visible=false;
                    app.tt_minEditField.Visible=false;
                    app.tt_maxEditField.Visible=false;
                    app.tt_incrEditField.Visible=false;
                    app.ttEditFieldLabel.Visible=false;
                    app.tt_minEditFieldLabel.Visible=false;
                    app.tt_maxEditFieldLabel.Visible=false;
                    app.tt_incrEditFieldLabel.Visible=false;
                    app.hEditField.Visible=false;
                    app.h_minEditField.Visible=true;
                    app.h_maxEditField.Visible=true;
                    app.h_incrEditField.Visible=true;
                    app.hEditFieldLabel.Visible=false;
                    app.h_minEditFieldLabel.Visible=true;
                    app.h_maxEditFieldLabel.Visible=true;
                    app.h_incrEditFieldLabel.Visible=true;
                    app.PanelTubeScan.Visible=false;
                    app.PanelDetectorScan.Visible=false;
                    app.PanelRockingCurve.Visible=false;
                    app.PanelHeightScan.Visible=true;
                    app.Panelt2t.Visible=false;
                    app.PanelDetectorImage.Visible=false;
                    app.PanelDetectorImageSarm.Visible=false;
                    %Adjust Plot Number Spinner
                    Nplots=length(app.AxesHeightScan.Children);
                    app.PlotNumberSpinner.Value=1;
                    if Nplots>1
                        app.PlotNumberSpinner.Limits=[1 Nplots];
                        app.PlotNumberSpinner.Visible=true;
                    else
                        app.PlotNumberSpinner.Visible=false;
                    end
                case 't2t'
                    app.alphaEditField.Visible=false;
                    app.alpha_minEditField.Visible=false;
                    app.alpha_maxEditField.Visible=false;
                    app.alpha_incrEditField.Visible=false;
                    app.alphaEditFieldLabel.Visible=false;
                    app.alpha_minLabel.Visible=false;
                    app.alpha_maxEditFieldLabel.Visible=false;
                    app.alpha_incrEditFieldLabel.Visible=false;
                    app.betaEditField.Visible=false;
                    app.beta_minEditField.Visible=false;
                    app.beta_maxEditField.Visible=false;
                    app.beta_incrEditField.Visible=false;
                    app.betaEditFieldLabel.Visible=false;
                    app.beta_minEditFieldLabel.Visible=false;
                    app.beta_maxEditFieldLabel.Visible=false;
                    app.beta_incrEditFieldLabel.Visible=false;
                    app.ttEditField.Visible=false;
                    app.tt_minEditField.Visible=true;
                    app.tt_maxEditField.Visible=true;
                    app.tt_incrEditField.Visible=true;
                    app.ttEditFieldLabel.Visible=false;
                    app.tt_minEditFieldLabel.Visible=true;
                    app.tt_maxEditFieldLabel.Visible=true;
                    app.tt_incrEditFieldLabel.Visible=true;
                    app.hEditField.Visible=true;
                    app.h_minEditField.Visible=false;
                    app.h_maxEditField.Visible=false;
                    app.h_incrEditField.Visible=false;
                    app.hEditFieldLabel.Visible=true;
                    app.h_minEditFieldLabel.Visible=false;
                    app.h_maxEditFieldLabel.Visible=false;
                    app.h_incrEditFieldLabel.Visible=false;
                    app.PanelTubeScan.Visible=false;
                    app.PanelDetectorScan.Visible=false;
                    app.PanelRockingCurve.Visible=false;
                    app.PanelHeightScan.Visible=false;
                    app.Panelt2t.Visible=true;
                    app.PanelDetectorImage.Visible=false;
                    app.PanelDetectorImageSarm.Visible=false;
                    %Adjust Plot Number Spinner
                    Nplots=length(app.Axest2t.Children);
                    app.PlotNumberSpinner.Value=1;
                    if Nplots>1
                        app.PlotNumberSpinner.Limits=[1 Nplots];
                        app.PlotNumberSpinner.Visible=true;
                    else
                        app.PlotNumberSpinner.Visible=false;
                    end
                case 'Detector Image'
                    app.alphaEditField.Visible=true;
                    app.alpha_minEditField.Visible=false;
                    app.alpha_maxEditField.Visible=false;
                    app.alpha_incrEditField.Visible=false;
                    app.alphaEditFieldLabel.Visible=true;
                    app.alpha_minLabel.Visible=false;
                    app.alpha_maxEditFieldLabel.Visible=false;
                    app.alpha_incrEditFieldLabel.Visible=false;
                    app.betaEditField.Visible=true;
                    app.beta_minEditField.Visible=false;
                    app.beta_maxEditField.Visible=false;
                    app.beta_incrEditField.Visible=false;
                    app.betaEditFieldLabel.Visible=true;
                    app.beta_minEditFieldLabel.Visible=false;
                    app.beta_maxEditFieldLabel.Visible=false;
                    app.beta_incrEditFieldLabel.Visible=false;
                    app.ttEditField.Visible=false;
                    app.tt_minEditField.Visible=false;
                    app.tt_maxEditField.Visible=false;
                    app.tt_incrEditField.Visible=false;
                    app.ttEditFieldLabel.Visible=false;
                    app.tt_minEditFieldLabel.Visible=false;
                    app.tt_maxEditFieldLabel.Visible=false;
                    app.tt_incrEditFieldLabel.Visible=false;
                    app.hEditField.Visible=false;
                    app.h_minEditField.Visible=false;
                    app.h_maxEditField.Visible=false;
                    app.h_incrEditField.Visible=false;
                    app.hEditFieldLabel.Visible=false;
                    app.h_minEditFieldLabel.Visible=false;
                    app.h_maxEditFieldLabel.Visible=false;
                    app.h_incrEditFieldLabel.Visible=false;
                    app.PanelTubeScan.Visible=false;
                    app.PanelDetectorScan.Visible=false;
                    app.PanelRockingCurve.Visible=false;
                    app.PanelHeightScan.Visible=false;
                    app.Panelt2t.Visible=false;
                    app.PanelDetectorImage.Visible=true;
                    app.PanelDetectorImageSarm.Visible=false;
                    %Adjust Plot Number Spinner
                    Nplots=length(app.AxesDetectorImage.Children);
                    app.PlotNumberSpinner.Value=1;
                    if Nplots>1
                        app.PlotNumberSpinner.Limits=[1 Nplots];
                        app.PlotNumberSpinner.Visible=true;
                    else
                        app.PlotNumberSpinner.Visible=false;
                    end
                case 'Detector Image Sourcearm'
                    app.alphaEditField.Visible=false;
                    app.alpha_minEditField.Visible=false;
                    app.alpha_maxEditField.Visible=false;
                    app.alpha_incrEditField.Visible=false;
                    app.alphaEditFieldLabel.Visible=false;
                    app.alpha_minLabel.Visible=false;
                    app.alpha_maxEditFieldLabel.Visible=false;
                    app.alpha_incrEditFieldLabel.Visible=false;
                    app.betaEditField.Visible=false;
                    app.beta_minEditField.Visible=false;
                    app.beta_maxEditField.Visible=false;
                    app.beta_incrEditField.Visible=false;
                    app.betaEditFieldLabel.Visible=false;
                    app.beta_minEditFieldLabel.Visible=false;
                    app.beta_maxEditFieldLabel.Visible=false;
                    app.beta_incrEditFieldLabel.Visible=false;
                    app.ttEditField.Visible=false;
                    app.tt_minEditField.Visible=false;
                    app.tt_maxEditField.Visible=false;
                    app.tt_incrEditField.Visible=false;
                    app.ttEditFieldLabel.Visible=false;
                    app.tt_minEditFieldLabel.Visible=false;
                    app.tt_maxEditFieldLabel.Visible=false;
                    app.tt_incrEditFieldLabel.Visible=false;
                    app.hEditField.Visible=false;
                    app.h_minEditField.Visible=false;
                    app.h_maxEditField.Visible=false;
                    app.h_incrEditField.Visible=false;
                    app.hEditFieldLabel.Visible=false;
                    app.h_minEditFieldLabel.Visible=false;
                    app.h_maxEditFieldLabel.Visible=false;
                    app.h_incrEditFieldLabel.Visible=false;
                    app.PanelTubeScan.Visible=false;
                    app.PanelDetectorScan.Visible=false;
                    app.PanelRockingCurve.Visible=false;
                    app.PanelHeightScan.Visible=false;
                    app.Panelt2t.Visible=false;
                    app.PanelDetectorImage.Visible=false;
                    app.PanelDetectorImageSarm.Visible=true;
                    %Adjust Plot Number Spinner
                    Nplots=length(app.AxesDetectorImageSarm.Children);
                    app.PlotNumberSpinner.Value=1;
                    if Nplots>1
                        app.PlotNumberSpinner.Limits=[1 Nplots];
                        app.PlotNumberSpinner.Visible=true;
                    else
                        app.PlotNumberSpinner.Visible=false;
                    end
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            switch app.ScantypeDropDown.Value
                case 'Tube Scan'
                    %Create Waiting Bar
                    d = uiprogressdlg(app.UIFigure,'Title','Measurement in Progress','Message','Start Measurement','Cancelable','on');
                    
                    %Variables for Tube Scan
                    alpha_min=app.alpha_minEditField.Value;
                    alpha_max=app.alpha_maxEditField.Value;
                    incr=app.alpha_incrEditField.Value;
                    beta=app.betaEditField.Value;
                    setup=app.setup;
                    
                    %Validate inputs
                    if alpha_min>alpha_max
                        app.ListBox.Items{end+1}='Could not measure: alpha_min must be smaller than alpha_max';
                        app.ListBox.scroll('bottom');
                        return;
                    end
                    
                    %Scan
                    %rotate detector arm by angle beta, leave it there.
                    setup1=rotate_darm(setup,beta);
                    
                    alpha=alpha_min:incr:alpha_max; %Vector of measured detector arm angles beta
                    J=zeros(1,length(alpha)); %Intensity vector
                    
                    %measure sourceslits
                    setup_red=measure_sslits(setup1);
                    for i=1:length(alpha)
                        % Check for Cancel button press
                        if d.CancelRequested
                            app.flag = true;
                            break
                        end
                        setup2=rotate_sarm(setup_red,alpha(i)); %Rotate sarm to angle alpha
                        J(i)=measure_d(setup2,1); %measure
                        d.Value=i/length(alpha); %Update wating bar
                        d.Message=append('alpha= ',num2str(alpha(i)));
                    end
                    %End Waiting bar
                    close(d);
                    %If cancel button has been pressed
                    if app.flag == true
                        app.flag =false;
                        return
                    end
                    
                    %Plot result
                    if app.MultiplePlotsCheckBox.Value==false
                        hold(app.AxesTubeScan,'off');
                        app.measurements.TubeScan={[alpha;J]};
                    else
                        hold(app.AxesTubeScan,'on');
                        app.measurements.TubeScan{end+1}=[alpha;J];
                    end
                    plot(app.AxesTubeScan,alpha,J,'.-');
                    legend(app.AxesTubeScan);
                    %Adjust Plot Number Spinner
                    Nplots=length(app.AxesTubeScan.Children);
                    app.PlotNumberSpinner.Value=1;
                    if Nplots>1
                        app.PlotNumberSpinner.Limits=[1 Nplots];
                        app.PlotNumberSpinner.Visible=true;
                    else
                        app.PlotNumberSpinner.Visible=false;
                    end
                case 'Detector Scan'
                    %Create Waiting Bar
                    d = uiprogressdlg(app.UIFigure,'Title','Measurement in Progress','Message','Start Measurement','Cancelable','on');
                    
                    %Variables for Detector Scan
                    beta_min=app.beta_minEditField.Value;
                    beta_max=app.beta_maxEditField.Value;
                    incr=app.beta_incrEditField.Value;
                    alpha=app.alphaEditField.Value;
                    setup=app.setup;
                    
                    %Validate inputs
                    if beta_min>beta_max
                        app.ListBox.Items{end+1}='Could not measure: beta_min must be smaller than beta_max';
                        app.ListBox.scroll('bottom');
                        return;
                    end
                    
                    %Scan
                    
                    %take only those rays that pass sslit
                    setup_red=measure_sslits(setup);
                    %rotate source arm by angle alpha, leave it there.
                    setup1=rotate_sarm(setup_red,alpha);
    
                    beta=beta_min:incr:beta_max; %Vector of measured detector arm angles beta
                    J=zeros(1,length(beta)); %Intensity vector
                    for i=1:length(beta)
                        % Check for Cancel button press
                        if d.CancelRequested
                            app.flag = true;
                            break
                        end
                        setup2=rotate_darm(setup1,beta(i)); %Rotate darm to angle beta
                        J(i)=measure_d(setup2,1); %measure
                        d.Value=i/length(beta); %Update wating bar
                        d.Message=append('beta= ',num2str(beta(i)));
                    end
                    
                    %End Waiting Bar
                    close(d);
                    %if cancel button has been pressed
                    if app.flag == true
                        app.flag =false;
                        return
                    end
                    %Plot result
                    if app.MultiplePlotsCheckBox.Value==false
                        hold(app.AxesDetectorScan,'off');
                        app.measurements.DetectorScan={[beta;J]};
                    else
                        hold(app.AxesDetectorScan,'on');
                        app.measurements.DetectorScan{end+1}=[beta;J];
                    end
                    plot(app.AxesDetectorScan,beta,J,'.-');
                    legend(app.AxesDetectorScan);
                    %Adjust Plot Number Spinner
                    Nplots=length(app.AxesDetectorScan.Children);
                    app.PlotNumberSpinner.Value=1;
                    if Nplots>1
                        app.PlotNumberSpinner.Limits=[1 Nplots];
                        app.PlotNumberSpinner.Visible=true;
                    else
                        app.PlotNumberSpinner.Visible=false;
                    end
                case 'Detector Image'
                    detectorsr1=app.setup.detectors.r1;
                    if isempty(detectorsr1)==false
                        %Create Waiting Bar
                        d = uiprogressdlg(app.UIFigure,'Title','Measurement in Progress','Message','Measuring','Indeterminate','on');
                        
                        %Variables for Scan
                        alpha=app.alphaEditField.Value;
                        beta=app.betaEditField.Value;
                        
                        %Scan
                        [a,I]=Scan_DetectorImage(app.setup,alpha,beta,1);
                        
                        %Close waiting bar
                        close(d);
                        
                        %Plot result
                        if app.MultiplePlotsCheckBox.Value==false
                            hold(app.AxesDetectorImage,'off');
                            app.measurements.DetectorImage={[a;I]};
                        else
                            hold(app.AxesDetectorImage,'on');
                            app.measurements.DetectorImage{end+1}=[a;I];
                        end
                        plot(app.AxesDetectorImage,a,I,'.-');
                        legend(app.AxesDetectorImage);
                        %Adjust Plot Number Spinner
                        Nplots=length(app.AxesDetectorImage.Children);
                        app.PlotNumberSpinner.Value=1;
                        if Nplots>1
                            app.PlotNumberSpinner.Limits=[1 Nplots];
                            app.PlotNumberSpinner.Visible=true;
                        else
                            app.PlotNumberSpinner.Visible=false;
                        end
                    else
                        app.ListBox.Items{end+1}='Could not measure: No detector on detectorarm';
                        app.ListBox.scroll('bottom');
                        return;
                    end   
                case 'Detector Image Sourcearm'
                    detectorsr1=app.setup.detectorssarm.r1;
                    if isempty(detectorsr1)==false
                        %Create Waiting Bar
                        d = uiprogressdlg(app.UIFigure,'Title','Measurement in Progress','Message','Measuring','Indeterminate','on');
                        [a,I]=Scan_DetectorImageSarm(app.setup,1);
                        close(d);
                        if app.MultiplePlotsCheckBox.Value==false
                            hold(app.AxesDetectorImageSarm,'off');
                            app.measurements.DetectorImageSarm={[a;I]};
                        else
                            hold(app.AxesDetectorImageSarm,'on');
                            app.measurements.DetectorImageSarm{end+1}=[a;I];
                        end
                        plot(app.AxesDetectorImageSarm,a,I,'.-');
                        legend(app.AxesDetectorImageSarm);
                        %Adjust Plot Number Spinner
                        Nplots=length(app.AxesDetectorImageSarm.Children);
                        app.PlotNumberSpinner.Value=1;
                        if Nplots>1
                            app.PlotNumberSpinner.Limits=[1 Nplots];
                            app.PlotNumberSpinner.Visible=true;
                        else
                            app.PlotNumberSpinner.Visible=false;
                        end
                    else
                        app.ListBox.Items{end+1}='Could not measure: No detector on sourcearm';
                        app.ListBox.scroll('bottom');
                        return;
                    end
                case 'Height Scan'
                    if isempty(app.setup.sample.r1)==false
                        %Create Waiting Bar
                        d = uiprogressdlg(app.UIFigure,'Title','Measurement in Progress','Message','Measuring','Cancelable','on');
                        %Variables for Scan
                        alpha=app.alphaEditField.Value;
                        beta=app.betaEditField.Value;
                        h_min=app.h_minEditField.Value;
                        h_max=app.h_maxEditField.Value;
                        incr=app.h_incrEditField.Value;
                        setup=app.setup;
                        %Validate inputs
                        if h_min>h_max
                            app.ListBox.Items{end+1}='Could not measure: h_min must be smaller than h_max';
                            app.ListBox.scroll('bottom');
                            return;
                        end
                        
                        %Scan
                        
                        %rotate source arm by angle alpha, darm by beta.
                        setup1=rotate_sarm(setup,alpha);
                        setup1=rotate_darm(setup1,beta); 
                        
                        %take only those rays that pass sslit
                        setup_red=measure_sslits(setup1);
                         
                        h=h_min:incr:h_max; %Vector of measured detector arm angles beta
                        J=zeros(1,length(h)); %Intensity vector
                        for i=1:length(h)
                            %check if cancel button pushed
                            if d.CancelRequested
                                app.flag = true;
                                return
                            end
                            %move height
                            setup2=height(setup_red,h(i));
                            J(i)=measure_d_sample(setup2,1); %measure
                            d.Value=i/length(h); %Update wating bar
                            d.Message=append('h= ',num2str(h(i)));
                        end
                        close(d);
                        %if cancel button has been pressed
                        if app.flag == true
                            app.flag =false;
                            return
                        end
                        %plot
                        if app.MultiplePlotsCheckBox.Value==false
                            hold(app.AxesHeightScan,'off');
                            app.measurements.HeightScan={[h;J]};
                        else
                            hold(app.AxesHeightScan,'on');
                            app.measurements.HeightScan{end+1}=[h;J];
                        end
                        plot(app.AxesHeightScan,h,J,'.-');
                        legend(app.AxesHeightScan);
                        %Adjust Plot Number Spinner
                        Nplots=length(app.AxesHeightScan.Children);
                        app.PlotNumberSpinner.Value=1;
                        if Nplots>1
                            app.PlotNumberSpinner.Limits=[1 Nplots];
                            app.PlotNumberSpinner.Visible=true;
                        else
                            app.PlotNumberSpinner.Visible=false;
                        end
                    else
                        app.ListBox.Items{end+1}='Could not measure: No sample in setup';
                        app.ListBox.scroll('bottom');
                        return;
                    end
                case 'Rocking Curve'
                    if isempty(app.setup.sample.r1)==false
                        %Create Waiting Bar
                        d = uiprogressdlg(app.UIFigure,'Title','Measurement in Progress','Message','Measuring','Cancelable','on');
                        %Variables for Scan
                        tt=app.ttEditField.Value;
                        h=app.hEditField.Value;
                        alpha_min=app.alpha_minEditField.Value;
                        alpha_max=app.alpha_maxEditField.Value;
                        incr=app.alpha_incrEditField.Value;
                        setup=app.setup;
                        %Validate inputs
                        if alpha_min>alpha_max
                            app.ListBox.Items{end+1}='Could not measure: alpha_min must be smaller than alpha_max';
                            app.ListBox.scroll('bottom');
                            return;
                        end
                        %Scan
                        setup_red=measure_sslits(setup);
                        setup_red=height(setup_red,h);
                        alpha=alpha_min:incr:alpha_max; %Vector of measured detector arm angles beta
                        J=zeros(1,length(alpha)); %Intensity vector
                        for i=1:length(alpha)
                            %check if cancel button pushed
                            if d.CancelRequested
                                app.flag = true;
                                return
                            end
                            %move to next alpha
                            setup1=rotate_sarm(setup_red,alpha(i));
                            setup1=rotate_darm(setup1,tt-alpha(i)); 
                            %measure
                            J(i)=measure_d_sample(setup1,1); 
                            d.Value=i/length(alpha); %Update wating bar
                            d.Message=append('alpha= ',num2str(alpha(i)));
                        end
                        close(d);
                        %if cancel button has been pressed
                        if app.flag == true
                            app.flag =false;
                            return
                        end
                        if app.MultiplePlotsCheckBox.Value==false
                            hold(app.AxesRockingCurve,'off');
                            app.measurements.RockingCurve={[alpha;J]};
                        else
                            hold(app.AxesRockingCurve,'on');
                            app.measurements.RockingCurve{end+1}=[alpha;J];
                        end
                        plot(app.AxesRockingCurve,alpha,J,'.-');
                        legend(app.AxesRockingCurve);
                        %Adjust Plot Number Spinner
                        Nplots=length(app.AxesRockingCurve.Children);
                        app.PlotNumberSpinner.Value=1;
                        if Nplots>1
                            app.PlotNumberSpinner.Limits=[1 Nplots];
                            app.PlotNumberSpinner.Visible=true;
                        else
                            app.PlotNumberSpinner.Visible=false;
                        end
                    else
                        app.ListBox.Items{end+1}='Could not measure: No sample in setup';
                        app.ListBox.scroll('bottom');
                        return;
                    end
                case 't2t'
                    if isempty(app.setup.sample.r1)==false
                        %Create Waiting Bar
                        d = uiprogressdlg(app.UIFigure,'Title','Measurement in Progress','Message','Measuring','Cancelable','on');
                        %Variables for Scan
                        h=app.hEditField.Value;
                        tt_min=app.tt_minEditField.Value;
                        tt_max=app.tt_maxEditField.Value;
                        incr=app.tt_incrEditField.Value;
                        setup=app.setup;
                        %Validate inputs
                        if tt_min>tt_max
                            app.ListBox.Items{end+1}='Could not measure: tt_min must be smaller than tt_max';
                            app.ListBox.scroll('bottom');
                            return;
                        end
                        %Scan
                        
                        %take only those rays that pass sslit
                        setup_red=measure_sslits(setup);
                        setup_red=height(setup_red,h);
                        
                        alpha_min=tt_min/2;
                        alpha_max=tt_max/2;
                        alpha=alpha_min:incr/2:alpha_max; %Vector of measured detector arm angles beta
                        tt=2*alpha;
                        J=zeros(1,length(alpha)); %Intensity vector
                        for i=1:length(alpha)
                            %check if cancel button pushed
                            if d.CancelRequested
                                app.flag = true;
                                return
                            end
                            %move to next al pha
                            setup1=rotate_sarm(setup_red,alpha(i));
                            setup1=rotate_darm(setup1,alpha(i)); 
                            J(i)=measure_d_sample(setup1,1); %measure
                            d.Value=i/length(tt); %Update wating bar
                            d.Message=append('tt= ',num2str(tt(i)));
                        end
                        close(d);
                        %if cancel button has been pressed
                        if app.flag == true
                            app.flag =false;
                            return
                        end
                        if app.MultiplePlotsCheckBox.Value==false
                            hold(app.Axest2t,'off');
                            app.measurements.t2t={[tt;J]};
                        else
                            hold(app.Axest2t,'on');
                            app.measurements.t2t{end+1}=[tt;J];
                        end
                        plot(app.Axest2t,tt,J,'.-');
                        legend(app.Axest2t);
                        %Adjust Plot Number Spinner
                        Nplots=length(app.Axest2t.Children);
                        app.PlotNumberSpinner.Value=1;
                        if Nplots>1
                            app.PlotNumberSpinner.Limits=[1 Nplots];
                            app.PlotNumberSpinner.Visible=true;
                        else
                            app.PlotNumberSpinner.Visible=false;
                        end
                    else
                        app.ListBox.Items{end+1}='Could not measure: No sample in setup';
                        app.ListBox.scroll('bottom');
                        return;
                    end
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            k=app.PlotNumberSpinner.Value;
            switch app.ScantypeDropDown.Value
                case 'Tube Scan'
                    if isempty(app.measurements.TubeScan)==false
                        [file,path,idx]=uiputfile('*.txt','Save Tube Scan data as .txt','TubeScan.txt');
                        drawnow; figure(app.UIFigure); %otherwise UIFigure minimizes
                        if idx==1 %if user pressed save
                            data=app.measurements.TubeScan{k};
                            alpha=data(1,:); alpha=alpha.';
                            J=data(2,:); J=J.';
                            t=table(alpha,J,'VariableNames',{'alpha','Intensity'});
                            writetable(t,[path file],'Delimiter',' ');
                        end
                    else
                        app.ListBox.Items{end+1}='There is no Tube Scan yet that could be saved. If you want to save another Scan, please select the right Scantype.';
                        app.ListBox.scroll('bottom');
                    end
                case 'Detector Scan'
                    if isempty(app.measurements.DetectorScan)==false
                        [file,path,idx]=uiputfile('*.txt','Save Detector Scan data as .txt','DetectorScan.txt');
                        drawnow; figure(app.UIFigure); %otherwise UIFigure minimizes
                        if idx==1 %if user pressed save
                            data=app.measurements.DetectorScan{k};
                            beta=data(1,:); beta=beta.';
                            J=data(2,:); J=J.';
                            t=table(beta,J,'VariableNames',{'beta','Intensity'});
                            writetable(t,[path file],'Delimiter',' ');
                        end
                    else
                        app.ListBox.Items{end+1}='There is no Detector Scan yet that could be saved. If you want to save another Scan, please select the right Scantype.';
                        app.ListBox.scroll('bottom');
                    end
                case 'Height Scan'
                    if isempty(app.measurements.HeightScan)==false
                        [file,path,idx]=uiputfile('*.txt','Save Height Scan data as .txt','HeightScan.txt');
                        drawnow; figure(app.UIFigure); %otherwise UIFigure minimizes
                        if idx==1 %if user pressed save
                            data=app.measurements.HeightScan{k};
                            h=data(1,:); h=h.';
                            J=data(2,:); J=J.';
                            t=table(h,J,'VariableNames',{'height','Intensity'});
                            writetable(t,[path file],'Delimiter',' ');
                        end
                    else
                        app.ListBox.Items{end+1}='There is no Height Scan yet that could be saved. If you want to save another Scan, please select the right Scantype.';
                        app.ListBox.scroll('bottom');
                    end
                case 'Rocking Curve'
                    if isempty(app.measurements.RockingCurve)==false
                        [file,path,idx]=uiputfile('*.txt','Save Rocking Curve data as .txt','TubeScans.txt');
                        drawnow; figure(app.UIFigure); %otherwise UIFigure minimizes
                        if idx==1 %if user pressed save
                            data=app.measurements.RockingCurve{k};
                            alpha=data(1,:); alpha=alpha.';
                            J=data(2,:); J=J.';
                            t=table(alpha,J,'VariableNames',{'alpha','Intensity'});
                            writetable(t,[path file],'Delimiter',' ');
                        end
                    else
                        app.ListBox.Items{end+1}='There is no Rocking Curve yet that could be saved. If you want to save another Scan, please select the right Scantype.';
                        app.ListBox.scroll('bottom');
                    end
                case 't2t'
                    if isempty(app.measurements.t2t)==false
                        [file,path,idx]=uiputfile('*.txt','Save t2t Scan data as .txt','t2t.txt');
                        drawnow; figure(app.UIFigure); %otherwise UIFigure minimizes
                        if idx==1 %if user pressed save
                            data=app.measurements.t2t{k};
                            tt=data(1,:); tt=tt.';
                            J=data(2,:); J=J.';
                            t=table(tt,J,'VariableNames',{'tt','Intensity'});
                            writetable(t,[path file],'Delimiter',' ');
                        end
                    else
                        app.ListBox.Items{end+1}='There is no t2t Scan yet that could be saved. If you want to save another Scan, please select the right Scantype.';
                        app.ListBox.scroll('bottom');
                    end
                case 'Detector Image'
                    if isempty(app.measurements.DetectorImage)==false
                        [file,path,idx]=uiputfile('*.txt','Save Detector Image data as .txt','DetectorImage.txt');
                        drawnow; figure(app.UIFigure); %otherwise UIFigure minimizes
                        if idx==1 %if user pressed save
                            data=app.measurements.DetectorImage{k};
                            a=data(1,:); a=a.';
                            J=data(2,:); J=J.';
                            t=table(a,J,'VariableNames',{'Pixel location','Intensity'});
                            writetable(t,[path file],'Delimiter',' ');
                        end
                    else
                        app.ListBox.Items{end+1}='There is no Detector Image yet that could be saved. If you want to save another Scan, please select the right Scantype.';
                        app.ListBox.scroll('bottom');
                    end
                case 'Detector Image Sourcearm'
                    if isempty(app.measurements.DetectorImageSarm)==false
                        [file,path,idx]=uiputfile('*.txt','Save Detector Image Sourcearm data as .txt','DetectorImageSarm.txt');
                        drawnow; figure(app.UIFigure); %otherwise UIFigure minimizes
                        if idx==1 %if user pressed save
                            data=app.measurements.DetectorImageSarm{k};
                            a=data(1,:); a=a.';
                            J=data(2,:); J=J.';
                            t=table(a,J,'VariableNames',{'Pixel location','Intensity'});
                            writetable(t,[path file],'Delimiter',' ');
                        end
                    else
                        app.ListBox.Items{end+1}='There is no Sourcearm Detector Image yet that could be saved. If you want to save another Scan, please select the right Scantype.';
                        app.ListBox.scroll('bottom');
                    end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.TableGoebel = uitable(app.SetupTab);
            app.TableGoebel.ColumnName = {'d'; 'gamma'; 'fwhm'; 'NGoebel'; 'NRays'; 'delta'};
            app.TableGoebel.RowName = {};
            app.TableGoebel.ColumnSortable = true;
            app.TableGoebel.ColumnEditable = true;
            app.TableGoebel.CellSelectionCallback = createCallbackFcn(app, @TableGoebelCellSelection, true);
            app.TableGoebel.Tooltip = {''};
            app.TableSources = uitable(app.SetupTab);
            app.TableSources.ColumnName = {'d'; 'a'; 'gamma'; 'phi'; 'delta'; 'NRays'};
            app.TableSources.RowName = {};
            app.TableSources.ColumnSortable = true;
            app.TableSources.ColumnEditable = true;
            app.TableSources.CellSelectionCallback = createCallbackFcn(app, @TableSourcesCellSelection, true);
            app.TableSlits = uitable(app.SetupTab);
            app.TableSlits.ColumnName = {'arm'; 'd'; 'w'; 'gamma'};
            app.TableSlits.RowName = {};
            app.TableSlits.ColumnSortable = true;
            app.TableSlits.ColumnEditable = true;
            app.TableSlits.CellSelectionCallback = createCallbackFcn(app, @TableSlitsCellSelection, true);
            app.TableDetectors = uitable(app.SetupTab);
            app.TableDetectors.ColumnName = {'arm'; 'd'; 'w'; 'gamma'; 'N'};
            app.TableDetectors.RowName = {};
            app.TableDetectors.ColumnSortable = true;
            app.TableDetectors.ColumnEditable = true;
            app.TableDetectors.CellSelectionCallback = createCallbackFcn(app, @TableDetectorsCellSelection, true);
            app.TableDetectors.Tooltip = {''};
            app.GoebelLabel = uilabel(app.SetupTab);
            app.GoebelLabel.Text = 'Goebel';
            app.SourcesLabel = uilabel(app.SetupTab);
            app.SourcesLabel.Text = 'Sources';
            app.SlitsLabel = uilabel(app.SetupTab);
            app.SlitsLabel.Text = 'Slits';
            app.DetectorsLabel = uilabel(app.SetupTab);
            app.DetectorsLabel.Text = 'Detectors';
            app.CreateSetupButton = uibutton(app.SetupTab, 'push');
            app.CreateSetupButton.ButtonPushedFcn = createCallbackFcn(app, @CreateSetupButtonPushed, true);
            app.CreateSetupButton.Text = 'Create Setup';
            app.PlotSetupCheckBox = uicheckbox(app.SetupTab);
            app.PlotSetupCheckBox.Text = 'Plot Setup after creation';
            app.PlotSetupCheckBox.Value = true;
            app.TableSample = uitable(app.SetupTab);
            app.TableSample.ColumnName = {'w'; 'offset'; 'gamma'};
            app.TableSample.RowName = {};
            app.TableSample.ColumnSortable = true;
            app.TableSample.ColumnEditable = true;
            app.TableSample.Tooltip = {''};
            app.SampleLabel = uilabel(app.SetupTab);
            app.SampleLabel.Text = 'Sample';
            app.Panel = uipanel(app.SetupTab);
            app.ButtonGoebelPlus = uibutton(app.Panel, 'push');
            app.ButtonGoebelPlus.ButtonPushedFcn = createCallbackFcn(app, @ButtonGoebelPlusPushed, true);
            app.ButtonGoebelPlus.Text = '+';
            app.ButtonGoebelMinus = uibutton(app.Panel, 'push');
            app.ButtonGoebelMinus.ButtonPushedFcn = createCallbackFcn(app, @ButtonGoebelMinusPushed, true);
            app.ButtonGoebelMinus.Text = '-';
            app.ButtonGoebelUp = uibutton(app.Panel, 'push');
            app.ButtonGoebelUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonGoebelUpPushed, true);
            app.ButtonGoebelUp.Text = 'up';
            app.ButtonGoebelDown = uibutton(app.Panel, 'push');
            app.ButtonGoebelDown.ButtonPushedFcn = createCallbackFcn(app, @ButtonGoebelDownPushed, true);
            app.ButtonGoebelDown.Text = 'down';
            app.Panel_2 = uipanel(app.SetupTab);
            app.ButtonSourcesPlus = uibutton(app.Panel_2, 'push');
            app.ButtonSourcesPlus.ButtonPushedFcn = createCallbackFcn(app, @ButtonSourcesPlusPushed, true);
            app.ButtonSourcesPlus.Text = '+';
            app.ButtonSourcesMinus = uibutton(app.Panel_2, 'push');
            app.ButtonSourcesMinus.ButtonPushedFcn = createCallbackFcn(app, @ButtonSourcesMinusPushed, true);
            app.ButtonSourcesMinus.Text = '-';
            app.ButtonSourcesUp = uibutton(app.Panel_2, 'push');
            app.ButtonSourcesUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonSourcesUpPushed, true);
            app.ButtonSourcesUp.Text = 'up';
            app.ButtonSourcesDown = uibutton(app.Panel_2, 'push');
            app.ButtonSourcesDown.ButtonPushedFcn = createCallbackFcn(app, @ButtonSourcesDownPushed, true);
            app.ButtonSourcesDown.Text = 'down';
            app.Panel_3 = uipanel(app.SetupTab);
            app.ButtonSlitsPlus = uibutton(app.Panel_3, 'push');
            app.ButtonSlitsPlus.ButtonPushedFcn = createCallbackFcn(app, @ButtonSlitsPlusPushed, true);
            app.ButtonSlitsPlus.Text = '+';
            app.ButtonSlitsMinus = uibutton(app.Panel_3, 'push');
            app.ButtonSlitsMinus.ButtonPushedFcn = createCallbackFcn(app, @ButtonSlitsMinusPushed, true);
            app.ButtonSlitsMinus.Text = '-';
            app.ButtonSlitsUp = uibutton(app.Panel_3, 'push');
            app.ButtonSlitsUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonSlitsUpPushed, true);
            app.ButtonSlitsUp.Text = 'up';
            app.ButtonSlitsDown = uibutton(app.Panel_3, 'push');
            app.ButtonSlitsDown.ButtonPushedFcn = createCallbackFcn(app, @ButtonSlitsDownPushed, true);
            app.ButtonSlitsDown.Text = 'down';
            app.Panel_4 = uipanel(app.SetupTab);
            app.ButtonDetectorsPlus = uibutton(app.Panel_4, 'push');
            app.ButtonDetectorsPlus.ButtonPushedFcn = createCallbackFcn(app, @ButtonDetectorsPlusPushed, true);
            app.ButtonDetectorsPlus.Text = '+';
            app.ButtonDetectorsMinus = uibutton(app.Panel_4, 'push');
            app.ButtonDetectorsMinus.ButtonPushedFcn = createCallbackFcn(app, @ButtonDetectorsMinusPushed, true);
            app.ButtonDetectorsMinus.Text = '-';
            app.ButtonDetectorsUp = uibutton(app.Panel_4, 'push');
            app.ButtonDetectorsUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonDetectorsUpPushed, true);
            app.ButtonDetectorsUp.Text = 'up';
            app.ButtonDetectorsDown = uibutton(app.Panel_4, 'push');
            app.ButtonDetectorsDown.ButtonPushedFcn = createCallbackFcn(app, @ButtonDetectorsDownPushed, true);
            app.ButtonDetectorsDown.Text = 'down';
            app.Panel_5 = uipanel(app.SetupTab);
            app.ButtonSamplePlus = uibutton(app.Panel_5, 'push');
            app.ButtonSamplePlus.ButtonPushedFcn = createCallbackFcn(app, @ButtonSamplePlusPushed, true);
            app.ButtonSamplePlus.Text = '+';
            app.ButtonSampleMinus = uibutton(app.Panel_5, 'push');
            app.ButtonSampleMinus.ButtonPushedFcn = createCallbackFcn(app, @ButtonSampleMinusPushed, true);
            app.ButtonSampleMinus.Text = '-';
            app.SetupAxes = uiaxes(app.SetupTab);
            title(app.SetupAxes, 'Setup')
            xlabel(app.SetupAxes, 'X [mm]')
            ylabel(app.SetupAxes, 'Y [mm]')
            app.Panel_6 = uipanel(app.SetupTab);
            app.detectorslitsLabel = uilabel(app.Panel_6);
            app.detectorslitsLabel.Text = 'detector slits';
            app.sampleLabel = uilabel(app.Panel_6);
            app.sampleLabel.Text = 'sample';
            app.GoebelsourcesLabel = uilabel(app.Panel_6);
            app.GoebelsourcesLabel.Text = 'Goebel / sources';
            app.sourceslitsLabel = uilabel(app.Panel_6);
            app.sourceslitsLabel.Text = 'source slits';
            app.detectorsLabel = uilabel(app.Panel_6);
            app.detectorsLabel.Text = 'detectors';
            app.BeamonCheckbox = uicheckbox(app.SetupTab);
            app.BeamonCheckbox.Text = 'Plot with beams';
            app.iLabel = uilabel(app.SetupTab);
            app.iLabel.Tooltip = {'d [mm]: distance on the source arm'; 'gamma [°]: rotation with respect to source arm'; 'fwhm [mm]: corresponds to distribution of sources perp. to source arm'; 'NGoebel: Number of sources on mirror'; 'delta [°]: divergence angle as the FWHM of the corresponding Gaussian distribution'};
            app.iLabel.Text = 'i';
            app.iLabel_2 = uilabel(app.SetupTab);
            app.iLabel_2.Tooltip = {'d [mm]: distance on the source arm'; 'a [mm]: distance perpendicular to source arm'; 'gamma [°]: rotation with respect to source arm'; 'phi [°]: mean angle of emitted rays with respect to source arm (center of Gaussian distribution)'; 'delta [°]: divergence angle of the rays as the FWHM of corresponding Gaussian distribution'; ''};
            app.iLabel_2.Text = 'i';
            app.iLabel_3 = uilabel(app.SetupTab);
            app.iLabel_3.Tooltip = {'d[mm]: distance on respective arm'; 'w [mm]: slit width'; 'gamma [°]: rotation of the slit with respect to the arm'};
            app.iLabel_3.Text = 'i';
            app.iLabel_4 = uilabel(app.SetupTab);
            app.iLabel_4.Tooltip = {'d [mm]: distance on arm'; 'w [mm]: width of the detector'; 'gamma [°]: rotation with respect to the arm'};
            app.iLabel_4.Text = 'i';
            app.iLabel_5 = uilabel(app.SetupTab);
            app.iLabel_5.Tooltip = {'w [mm]: sample length'; 'offset [mm]: offset in x direction'; 'gamma [mm]: angle of rotation with respect to the x axis'};
            app.iLabel_5.Text = 'i';
            app.iLabel_6 = uilabel(app.SetupTab);
            app.iLabel_6.Tooltip = {'The slit openings are colored.'};
            app.iLabel_6.Text = 'i';
            app.MeasureTab = uitab(app.TabGroup);
            app.MeasureTab.Title = 'Measure';
            app.ScantypeDropDownLabel = uilabel(app.MeasureTab);
            app.ScantypeDropDownLabel.Text = 'Scantype';
            app.ScantypeDropDown = uidropdown(app.MeasureTab);
            app.ScantypeDropDown.Items = {'Tube Scan', 'Detector Scan', 'Rocking Curve', 'Height Scan', 't2t', 'Detector Image', 'Detector Image Sourcearm'};
            app.ScantypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ScantypeDropDownValueChanged, true);
            app.ScantypeDropDown.Value = 'Tube Scan';
            app.StartButton = uibutton(app.MeasureTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.alphaEditFieldLabel = uilabel(app.MeasureTab);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.MeasureTab, 'numeric');
            app.betaEditFieldLabel = uilabel(app.MeasureTab);
            app.betaEditFieldLabel.Text = 'beta';
            app.betaEditField = uieditfield(app.MeasureTab, 'numeric');
            app.ttEditFieldLabel = uilabel(app.MeasureTab);
            app.ttEditFieldLabel.Text = 'tt';
            app.ttEditField = uieditfield(app.MeasureTab, 'numeric');
            app.hEditFieldLabel = uilabel(app.MeasureTab);
            app.hEditFieldLabel.Text = 'h';
            app.hEditField = uieditfield(app.MeasureTab, 'numeric');
            app.alpha_maxEditFieldLabel = uilabel(app.MeasureTab);
            app.alpha_maxEditFieldLabel.Text = 'alpha_max';
            app.alpha_maxEditField = uieditfield(app.MeasureTab, 'numeric');
            app.beta_maxEditFieldLabel = uilabel(app.MeasureTab);
            app.beta_maxEditFieldLabel.Text = 'beta_max';
            app.beta_maxEditField = uieditfield(app.MeasureTab, 'numeric');
            app.tt_maxEditFieldLabel = uilabel(app.MeasureTab);
            app.tt_maxEditFieldLabel.Text = 'tt_max';
            app.tt_maxEditField = uieditfield(app.MeasureTab, 'numeric');
            app.h_maxEditFieldLabel = uilabel(app.MeasureTab);
            app.h_maxEditFieldLabel.Text = 'h_max';
            app.h_maxEditField = uieditfield(app.MeasureTab, 'numeric');
            app.alpha_incrEditFieldLabel = uilabel(app.MeasureTab);
            app.alpha_incrEditFieldLabel.Text = 'alpha_incr';
            app.alpha_incrEditField = uieditfield(app.MeasureTab, 'numeric');
            app.beta_incrEditFieldLabel = uilabel(app.MeasureTab);
            app.beta_incrEditFieldLabel.Text = 'beta_incr';
            app.beta_incrEditField = uieditfield(app.MeasureTab, 'numeric');
            app.tt_incrEditFieldLabel = uilabel(app.MeasureTab);
            app.tt_incrEditFieldLabel.Text = 'tt_incr';
            app.tt_incrEditField = uieditfield(app.MeasureTab, 'numeric');
            app.h_incrEditFieldLabel = uilabel(app.MeasureTab);
            app.h_incrEditFieldLabel.Text = 'h_incr';
            app.h_incrEditField = uieditfield(app.MeasureTab, 'numeric');
            app.alpha_minLabel = uilabel(app.MeasureTab);
            app.alpha_minLabel.Text = 'alpha_min';
            app.alpha_minEditField = uieditfield(app.MeasureTab, 'numeric');
            app.beta_minEditFieldLabel = uilabel(app.MeasureTab);
            app.beta_minEditFieldLabel.Text = 'beta_min';
            app.beta_minEditField = uieditfield(app.MeasureTab, 'numeric');
            app.tt_minEditFieldLabel = uilabel(app.MeasureTab);
            app.tt_minEditFieldLabel.Text = 'tt_min';
            app.tt_minEditField = uieditfield(app.MeasureTab, 'numeric');
            app.h_minEditFieldLabel = uilabel(app.MeasureTab);
            app.h_minEditFieldLabel.Text = 'h_min';
            app.h_minEditField = uieditfield(app.MeasureTab, 'numeric');
            app.Label = uilabel(app.MeasureTab);
            app.Label.Text = 'Please create a Setup in the Setup tab before starting your measurement.';
            app.PanelTubeScan = uipanel(app.MeasureTab);
            app.AxesTubeScan = uiaxes(app.PanelTubeScan);
            title(app.AxesTubeScan, 'Plot')
            xlabel(app.AxesTubeScan, 'alpha [°]')
            ylabel(app.AxesTubeScan, 'Intensity counts')
            app.PanelDetectorScan = uipanel(app.MeasureTab);
            app.AxesDetectorScan = uiaxes(app.PanelDetectorScan);
            title(app.AxesDetectorScan, 'Plot')
            xlabel(app.AxesDetectorScan, 'beta [°]')
            ylabel(app.AxesDetectorScan, 'Intensity counts')
            app.PanelRockingCurve = uipanel(app.MeasureTab);
            app.AxesRockingCurve = uiaxes(app.PanelRockingCurve);
            title(app.AxesRockingCurve, 'Plot')
            xlabel(app.AxesRockingCurve, 'alpha [*]')
            ylabel(app.AxesRockingCurve, 'Intensity counts')
            app.PanelHeightScan = uipanel(app.MeasureTab);
            app.AxesHeightScan = uiaxes(app.PanelHeightScan);
            title(app.AxesHeightScan, 'Plot')
            xlabel(app.AxesHeightScan, 'h [mm]')
            ylabel(app.AxesHeightScan, 'Intensity counts')
            app.Panelt2t = uipanel(app.MeasureTab);
            app.Axest2t = uiaxes(app.Panelt2t);
            title(app.Axest2t, 'Plot')
            xlabel(app.Axest2t, 't2t [°]')
            ylabel(app.Axest2t, 'Intensity counts')
            app.PanelDetectorImage = uipanel(app.MeasureTab);
            app.AxesDetectorImage = uiaxes(app.PanelDetectorImage);
            title(app.AxesDetectorImage, 'Plot')
            xlabel(app.AxesDetectorImage, 'a [mm]')
            ylabel(app.AxesDetectorImage, 'Intensity counts')
            app.PanelDetectorImageSarm = uipanel(app.MeasureTab);
            app.AxesDetectorImageSarm = uiaxes(app.PanelDetectorImageSarm);
            title(app.AxesDetectorImageSarm, 'Plot')
            xlabel(app.AxesDetectorImageSarm, 'a [mm]')
            ylabel(app.AxesDetectorImageSarm, 'Intensity counts')
            app.MultiplePlotsCheckBox = uicheckbox(app.MeasureTab);
            app.MultiplePlotsCheckBox.Text = 'Multiple Plots';
            app.SaveButton = uibutton(app.MeasureTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.PlotNumberSpinnerLabel = uilabel(app.MeasureTab);
            app.PlotNumberSpinnerLabel.Text = 'Plot Number';
            app.PlotNumberSpinner = uispinner(app.MeasureTab);
            app.PlotNumberSpinner.Limits = [1 2];
            app.PlotNumberSpinner.Value = 1;
            app.iLabel_7 = uilabel(app.MeasureTab);
            app.iLabel_7.Tooltip = {'alpha [°]: angle source arm'; 'beta [°]: angle detector arm'; 'tt [°]: angle 2theta = alpha + beta'; 'h [mm]: sample height'};
            app.iLabel_7.Text = 'i';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RayTracingProgram

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end